classdef ModalAnalysisAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SmallsignalSystemStrengthLabel  matlab.ui.control.Label
        WarningbyzoomingLabel           matlab.ui.control.Label
        TextArea_3                      matlab.ui.control.TextArea
        Layer3Label                     matlab.ui.control.Label
        Step4refreshtheAPPifthesystemischangedLabel  matlab.ui.control.Label
        ModeIndexText                   matlab.ui.control.NumericEditField
        TraditionalstatespacePFLabel    matlab.ui.control.Label
        SSTable                         matlab.ui.control.Table
        RefreshButton                   matlab.ui.control.Button
        byYueZhuyuezhu18imperialacukLabel  matlab.ui.control.Label
        TuningTable                     matlab.ui.control.Table
        Label_2                         matlab.ui.control.Label
        TuningButton                    matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        FrequencyLabel                  matlab.ui.control.Label
        DampingLabel                    matlab.ui.control.Label
        Layer2Label                     matlab.ui.control.Label
        Layer1Label                     matlab.ui.control.Label
        Step3selectanapparatusinthetableandclickTuningbuttonLabel  matlab.ui.control.Label
        Step2clickRunbuttonLabel        matlab.ui.control.Label
        Step1selecttheinterestedmodeontheeigenvalueplotLabel  matlab.ui.control.Label
        IMRTable                        matlab.ui.control.Table
        TheGreyboxApporachAnalysisLabel  matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        ModeHzLabel                     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        ModeIndexLabel_2                matlab.ui.control.Label
        SelectedModeLabel               matlab.ui.control.Label
        BarChart2                       matlab.ui.control.UIAxes
        BarChart1                       matlab.ui.control.UIAxes
        PieChart                        matlab.ui.control.UIAxes
        ModePlot                        matlab.ui.control.UIAxes
    end

    properties (Access = public)
        GsysSs = evalin('base', 'GsysSs');
        Fbase = evalin('base', 'Fbase');
        ModeHz;
        ModeSelect=0;
        AppLegend;
        AppSelect;
        MdDataSave;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %pole_sys = evalin('base', 'pole_sys'); %get data from workspace
            %% draw pole map.
            [~,D] = eig(app.GsysSs.A);
            app.ModeHz=diag(D)/2/pi;
            f=app.ModePlot;
            lx=length(app.ModeHz);
            gx=cell(lx,1);
            mx = real(app.ModeHz);
            my = imag(app.ModeHz);
            for i=1:length(app.ModeHz)
                if (abs(my(i))>10*abs(mx(i)) && abs(abs(my(i))-app.Fbase)>0.3 ) || (mx(i)>0)% 10% damping and not around base frequency, or RHP
                    gx{i}='poorly tuned';
                else 
                    gx{i}='normal';
                end
            end
            gscatter(f,mx,my,gx,'br','xo',9);
            f.XLim=[-20,2];
            f.YLim=[-100,100];
            f.Title.String='System Modes (eigenvalue)';
            f.XLabel.String = 'Real (Hz)';
            f.XLabel.String = 'Imag (Hz)';

            %% Charts
            PieData=[1,1]/2;
            app.AppLegend=[{'A1'},{'A2'}];
            AppType=[{'GFL'},{'GFL'}];
            SimplusGT.Modal.Layer1PieChart(app.PieChart, PieData, app.AppLegend);
            SimplusGT.Modal.Layer2BarChart(app.BarChart1, PieData, app.AppLegend);
            SimplusGT.Modal.Layer2BarChart(app.BarChart2, PieData*(-1), app.AppLegend);
            %app.IMRTable.RowName=PieLegend;
            tdata=table(app.AppLegend.',AppType.',PieData.',PieData.');
            app.IMRTable.Data=tdata;
            app.AppSelect=1;
            app.TuningButton.Text=['Tune A',num2str(app.AppSelect)];
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            ax = app.UIFigure.CurrentAxes; % Gets the data from clicked axes
            axTitle=ax.Title.String;
            if(contains(axTitle,'eigenvalue')) %click on the eigenvalue plot
                pt = ax.CurrentPoint(1,1:2);
                mx = real(app.ModeHz(:));
                my = imag(app.ModeHz(:));
                %coordinates = [mx(:),my(:)];     % matrix of your input coordinates
                dist_m2 = ((mx(:)-pt(1)).^2+(my(:)-pt(2)).^2);
                %pdist2(pt,coordinates,'squaredeuclidean');
                if min(dist_m2)<=3
                    [~, ID] = min(dist_m2);            % index of minimum distance to points
                    app.ModeIndexText.Value=ID;
                    %app.ModeIndexLabel.Text=num2str(ID);
                    app.ModeHzLabel.Text= [num2str(mx(ID)),' + j',num2str(my(ID)),' Hz'];
                    app.ModeSelect=ID;
                end
            end
        end

        % Button down function: ModePlot
        function ModePlotButtonDown(app, event)
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if app.ModeSelect==0
                app.Label.Text = 'Select a mode first!';
            elseif abs(app.ModeHz(app.ModeSelect))==0
                app.Label.Text = '0+j0 is not allowed';
            else
                app.Label.Text = 'Calculating...';
                [MDResults, app.MdDataSave]=SimplusGT.Modal.AppModalAnalysis(app.ModeSelect); % get modal results.
                AppratusNum = length(MDResults.Layer1.result);
                % arrange the plotting data
                app.AppLegend=[{''}];
                for i=1:AppratusNum
                    PieData(i)=MDResults.Layer1.result(i).Abs_Max;
                    app.AppLegend(i)=MDResults.Layer1.result(i).Apparatus;
                    BarRealPu(i)=MDResults.Layer2.result(i).DeltaLambdaRealpu;
                    BarImagPu(i)=MDResults.Layer2.result(i).DeltaLambdaImagpu;
                    BarReal(i)=MDResults.Layer2.result(i).DeltaLambdaReal;
                    if BarReal(i)>0 && MDResults.IMR.IMRVal(i)<1
                        AppRole{i}='Weak: scale up';
                    elseif BarReal(i)<0 && MDResults.IMR.IMRVal(i)<1
                        AppRole{i}='Weak:scale down';
                    else
                        AppRole{i}='Strong';
                    end

                    type = MDResults.IMR.Type(i);
                    if type<10 || (type>=30 && type<40)
                        AppType{i}='SG';
                    elseif type>=10 && type<20
                        AppType{i}='GFL';
                    elseif type>=20 && type<30
                        AppType{i}='GFM';
                    else
                        AppType{i}='Unknown';
                    end
                    
                end

                %% Layer-1,2, charts
                SimplusGT.Modal.Layer1PieChart(app.PieChart, PieData, app.AppLegend);
                SimplusGT.Modal.Layer2BarChart(app.BarChart1, BarRealPu, app.AppLegend);
                SimplusGT.Modal.Layer2BarChart(app.BarChart2, BarImagPu, app.AppLegend);
                app.Label.Text = 'Finished!';
                %% IMR Table
                tdata=table(app.AppLegend.',AppType.',MDResults.IMR.IMRVal.',AppRole.');
                app.IMRTable.Data=tdata;
                %% State-space Table
                tdata3=table(MDResults.StatePF.StateString.', MDResults.StatePF.Val.');
                app.SSTable.Data=tdata3;
                app.SSTable.RowName=(1:length(app.GsysSs.A)).';
            end
        end

        % Cell selection callback: IMRTable
        function IMRTableCellSelection(app, event)
            indices = event.Indices;
            RowSel=indices(1,1);
            app.TuningButton.Text = ['Tune ',app.AppLegend{RowSel}];
            app.AppSelect = str2num(erase(app.AppLegend{RowSel},'A'));
        end

        % Button pushed function: TuningButton
        function TuningButtonPushed(app, event)
            if app.AppSelect==0
                app.Label_2.Text='Select an apparatus in the table!';
            elseif app.ModeSelect==0
                app.Label_2.Text='Select a mode first!';
            else
                app.Label_2.Text='analysing...';
                %MDResults=SimplusGT.Modal.AppModalAnalysis(app.ModeSelect,app.AppSelect,1);
                MdLayer3=SimplusGT.Modal.AppModalLayer3(app.AppSelect,app.MdDataSave);
                for i=1:length(MdLayer3.Result)
                    ParaName(i)=MdLayer3.Result(i).ParaName;
                    DLambdaPu(i)=MdLayer3.Result(i).DLambdaRho_pu_Hz;
                    if(real(DLambdaPu(i))>1e-3)
                        Guidance{i}='decrease';
                    elseif(real(DLambdaPu(i))<-1e-3)
                        Guidance{i}='increase';
                    else
                        Guidance{i}='';
                    end
                end
                tData2=table(ParaName.', DLambdaPu.', Guidance.');
                app.TuningTable.Data=tData2;
                app.Label_2.Text='finished...';
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.GsysSs = evalin('base', 'GsysSs');
            app.Fbase = evalin('base', 'Fbase');
            app.ModeHz;
            app.ModeSelect=0;
            app.AppLegend;
            app.AppSelect;
            startupFcn(app);
        end

        % Value changed function: ModeIndexText
        function ModeIndexTextValueChanged(app, event)
            ID = app.ModeIndexText.Value;
            if ID>length(app.GsysSs.A)
                app.Label.Text = 'Invalid input!';
            else
                mx = real(app.ModeHz(:));
                my = imag(app.ModeHz(:));
                app.ModeHzLabel.Text= [num2str(mx(ID)),' + j',num2str(my(ID)),' Hz'];
                app.ModeSelect=ID;
                app.Label.Text = '';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'lines');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.ModePlot = uiaxes(app.UIFigure);
            title(app.ModePlot, 'System Modes (eigenvalue)')
            xlabel(app.ModePlot, 'real (Hz)')
            ylabel(app.ModePlot, 'imag (Hz)')
            zlabel(app.ModePlot, 'Z')
            app.ModePlot.XLim = [-80 20];
            app.ModePlot.YLim = [-150 150];
            app.ModePlot.ButtonDownFcn = createCallbackFcn(app, @ModePlotButtonDown, true);
            colormap(app.ModePlot, 'lines')
            app.PieChart = uiaxes(app.UIFigure);
            app.PieChart.XTick = [0 0.2 0.4 0.6 0.8 1];
            colormap(app.PieChart, 'lines')
            app.BarChart1 = uiaxes(app.UIFigure);
            app.BarChart1.YLim = [-1 1];
            colormap(app.BarChart1, 'lines')
            app.BarChart2 = uiaxes(app.UIFigure);
            app.BarChart2.YLim = [-1 1];
            colormap(app.BarChart2, 'lines')
            app.SelectedModeLabel = uilabel(app.UIFigure);
            app.SelectedModeLabel.Text = 'Selected Mode:';
            app.ModeIndexLabel_2 = uilabel(app.UIFigure);
            app.ModeIndexLabel_2.Text = 'Mode Index';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ModeHzLabel = uilabel(app.UIFigure);
            app.ModeHzLabel.Text = '0+j0 Hz';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'This layer shows the variation of the mode in both real and imaginary part when increasing the impedance of the apparatus (equivilently as scaling down). '; 'General principle: if a positive bar is found in damping plot,  it implies that scaling up the corresponding apparatus connected at the node tends to stabilize the system , and vice versa. '; 'All values are normalised to 1.'};
            app.TheGreyboxApporachAnalysisLabel = uilabel(app.UIFigure);
            app.TheGreyboxApporachAnalysisLabel.Text = 'The Greybox Apporach Analysis';
            app.IMRTable = uitable(app.UIFigure);
            app.IMRTable.ColumnName = {'Apparatus'; 'Type'; 'IMR'; 'Strength Analysis'};
            app.IMRTable.RowName = {};
            app.IMRTable.CellSelectionCallback = createCallbackFcn(app, @IMRTableCellSelection, true);
            app.Step1selecttheinterestedmodeontheeigenvalueplotLabel = uilabel(app.UIFigure);
            app.Step1selecttheinterestedmodeontheeigenvalueplotLabel.Text = 'Step 1: select the interested mode on the eigenvalue plot, or enther the index number';
            app.Step2clickRunbuttonLabel = uilabel(app.UIFigure);
            app.Step2clickRunbuttonLabel.Text = 'Step 2: click ''Run'' button';
            app.Step3selectanapparatusinthetableandclickTuningbuttonLabel = uilabel(app.UIFigure);
            app.Step3selectanapparatusinthetableandclickTuningbuttonLabel.Text = 'Step 3: select an apparatus in the table and click ''Tuning'' button';
            app.Layer1Label = uilabel(app.UIFigure);
            app.Layer1Label.Text = 'Layer 1';
            app.Layer2Label = uilabel(app.UIFigure);
            app.Layer2Label.Text = 'Layer 2';
            app.DampingLabel = uilabel(app.UIFigure);
            app.DampingLabel.Text = 'Damping';
            app.FrequencyLabel = uilabel(app.UIFigure);
            app.FrequencyLabel.Text = 'Frequency';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'This layer helps you quickly determine the potential participants of the selected mode'};
            app.TuningButton = uibutton(app.UIFigure, 'push');
            app.TuningButton.ButtonPushedFcn = createCallbackFcn(app, @TuningButtonPushed, true);
            app.TuningButton.Text = 'Tuning';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.TuningTable = uitable(app.UIFigure);
            app.TuningTable.ColumnName = {'Parameter'; 'Influence on Mode (Hz)'; 'Guidance'};
            app.TuningTable.RowName = {};
            app.byYueZhuyuezhu18imperialacukLabel = uilabel(app.UIFigure);
            app.byYueZhuyuezhu18imperialacukLabel.Text = 'by Yue Zhu (yue.zhu18@imperial.ac.uk)';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.SSTable = uitable(app.UIFigure);
            app.SSTable.ColumnName = {'states'; 'PF'};
            app.SSTable.RowName = {};
            app.TraditionalstatespacePFLabel = uilabel(app.UIFigure);
            app.TraditionalstatespacePFLabel.Text = 'Traditional state-space PF';
            app.ModeIndexText = uieditfield(app.UIFigure, 'numeric');
            app.ModeIndexText.Limits = [1 Inf];
            app.ModeIndexText.ValueDisplayFormat = '%.0f';
            app.ModeIndexText.ValueChangedFcn = createCallbackFcn(app, @ModeIndexTextValueChanged, true);
            app.ModeIndexText.Value = 1;
            app.Step4refreshtheAPPifthesystemischangedLabel = uilabel(app.UIFigure);
            app.Step4refreshtheAPPifthesystemischangedLabel.Text = 'Step 4: refresh the APP if the system is changed';
            app.Layer3Label = uilabel(app.UIFigure);
            app.Layer3Label.Text = 'Layer 3';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'Traditional state-space participation factor clculated from left and right eigenvectors'};
            app.WarningbyzoomingLabel = uilabel(app.UIFigure);
            app.WarningbyzoomingLabel.Text = 'Warning: by zooming in the eigenvalu plot, the selection by mouse clicking may be wrong. Always check the selected mode and its index before running.';
            app.SmallsignalSystemStrengthLabel = uilabel(app.UIFigure);
            app.SmallsignalSystemStrengthLabel.Text = 'Small-signal System Strength';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModalAnalysisAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end