classdef ModalAnalysisAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        Step4refreshtheAPPifthesystemischangedLabel  matlab.ui.control.Label
        ModeIndexText              matlab.ui.control.NumericEditField
        RefreshButton              matlab.ui.control.Button
        Step3selectanapparatusinthetableandclickTuningbuttonLabel  matlab.ui.control.Label
        Step2clickRunbuttonLabel   matlab.ui.control.Label
        Step1selecttheinterestedmodeontheeigenvalueplotLabel  matlab.ui.control.Label
        ModeHzLabel                matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        RunButton                  matlab.ui.control.Button
        ModeIndexLabel_2           matlab.ui.control.Label
        SelectedModeLabel          matlab.ui.control.Label
        ModePlot                   matlab.ui.control.UIAxes
        CenterPanel                matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        GridLayout9                matlab.ui.container.GridLayout
        GridLayout12               matlab.ui.container.GridLayout
        PieChart                   matlab.ui.control.UIAxes
        ApparatusParticipationFactorLabel  matlab.ui.control.Label
        GridLayout8                matlab.ui.container.GridLayout
        GridLayout10               matlab.ui.container.GridLayout
        ParameterSensitivityLabel  matlab.ui.control.Label
        TuneButton                 matlab.ui.control.Button
        TuningTable                matlab.ui.control.Table
        GridLayout7                matlab.ui.container.GridLayout
        SmallsignalSystemStrengthLabel_2  matlab.ui.control.Label
        IMRTable                   matlab.ui.control.Table
        GridLayout3                matlab.ui.container.GridLayout
        BarChart1                  matlab.ui.control.UIAxes
        BarChart2                  matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
        GridLayout11               matlab.ui.container.GridLayout
        StateSpaceParticipationFactorLabel  matlab.ui.control.Label
        SSTable                    matlab.ui.control.Table
        TheGreyboxApporachAnalysisLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    properties (Access = public)
        GsysSs = evalin('base', 'GsysSs');
        Fbase = evalin('base', 'Fbase');
        ModeHz;
        ModeSelect=0;
        AppLegend;
        AppSelect;
        MdDataSave;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %pole_sys = evalin('base', 'pole_sys'); %get data from workspace
            %% draw pole map.
            [~,D] = eig(app.GsysSs.A);
            app.ModeHz=diag(D)/2/pi;
            f=app.ModePlot;
            lx=length(app.ModeHz);
            gx=cell(lx,1);
            mx = real(app.ModeHz);
            my = imag(app.ModeHz);
            for i=1:length(app.ModeHz)
                if (abs(my(i))>10*abs(mx(i)) && abs(abs(my(i))-app.Fbase)>0.3 ) || (mx(i)>0)% 10% damping and not around base frequency, or RHP
                    gx{i}='poorly tuned';
                else 
                    gx{i}='normal';
                end
            end
            gscatter(f,mx,my,gx,'br','xo',9);
            f.XLim=[-20,2];
            f.YLim=[-100,100];
            f.Title.String='System Modes (eigenvalue)';
            f.XLabel.String = 'Real (Hz)';
            f.XLabel.String = 'Imag (Hz)';

            %% Charts
            PieData=[1,1]/2;
            app.AppLegend=[{'A1'},{'A2'}];
            AppType=[{'GFL'},{'GFL'}];
            SimplusGT.Modal.Layer1PieChart(app.PieChart, PieData, app.AppLegend);
            SimplusGT.Modal.Layer2BarChart(app.BarChart1, PieData, app.AppLegend);
            SimplusGT.Modal.Layer2BarChart(app.BarChart2, PieData*(-1), app.AppLegend);
            %app.IMRTable.RowName=PieLegend;
            tdata=table(app.AppLegend.',AppType.',PieData.',PieData.');
            app.IMRTable.Data=tdata;
            app.AppSelect=1;
            app.TuneButton.Text=['Tune A',num2str(app.AppSelect)];
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            ax = app.UIFigure.CurrentAxes; % Gets the data from clicked axes
            axTitle=ax.Title.String;
            if(contains(axTitle,'eigenvalue')) %click on the eigenvalue plot
                pt = ax.CurrentPoint(1,1:2);
                mx = real(app.ModeHz(:));
                my = imag(app.ModeHz(:));
                %coordinates = [mx(:),my(:)];     % matrix of your input coordinates
                dist_m2 = ((mx(:)-pt(1)).^2+(my(:)-pt(2)).^2);
                %pdist2(pt,coordinates,'squaredeuclidean');
                if min(dist_m2)<=3
                    [~, ID] = min(dist_m2);            % index of minimum distance to points
                    app.ModeIndexText.Value=ID;
                    %app.ModeIndexLabel.Text=num2str(ID);
                    app.ModeHzLabel.Text= [num2str(mx(ID)),' + j',num2str(my(ID)),' Hz'];
                    app.ModeSelect=ID;
                end
            end
        end

        % Button down function: ModePlot
        function ModePlotButtonDown(app, event)
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if app.ModeSelect==0
                app.Label.Text = 'Select a mode first!';
            elseif abs(app.ModeHz(app.ModeSelect))==0
                app.Label.Text = '0+j0 is not allowed';
            else
                app.Label.Text = 'Calculating...';
                [MDResults, app.MdDataSave]=SimplusGT.Modal.AppModalAnalysis(app.ModeSelect); % get modal results.
                AppratusNum = length(MDResults.Layer1.result);
                % arrange the plotting data
                app.AppLegend=[{''}];
                for i=1:AppratusNum
                    PieData(i)=MDResults.Layer1.result(i).Abs_Max;
                    app.AppLegend(i)=MDResults.Layer1.result(i).Apparatus;
                    BarRealPu(i)=MDResults.Layer2.result(i).DeltaLambdaRealpu;
                    BarImagPu(i)=MDResults.Layer2.result(i).DeltaLambdaImagpu;
                    BarReal(i)=MDResults.Layer2.result(i).DeltaLambdaReal;
                    if BarReal(i)>0 && MDResults.IMR.IMRVal(i)<1
                        AppRole{i}='Weak: scale up';
                    elseif BarReal(i)<0 && MDResults.IMR.IMRVal(i)<1
                        AppRole{i}='Weak:scale down';
                    else
                        AppRole{i}='Strong';
                    end

                    type = MDResults.IMR.Type(i);
                    if type<10 || (type>=30 && type<40)
                        AppType{i}='SG';
                    elseif type>=10 && type<20
                        AppType{i}='GFL';
                    elseif type>=20 && type<30
                        AppType{i}='GFM';
                    else
                        AppType{i}='Unknown';
                    end
                    
                end

                %% Layer-1,2, charts
                SimplusGT.Modal.Layer1PieChart(app.PieChart, PieData, app.AppLegend);
                SimplusGT.Modal.Layer2BarChart(app.BarChart1, BarRealPu, app.AppLegend);
                SimplusGT.Modal.Layer2BarChart(app.BarChart2, BarImagPu, app.AppLegend);
                app.Label.Text = 'Finished!';
                %% IMR Table
                tdata=table(app.AppLegend.',AppType.',MDResults.IMR.IMRVal.',AppRole.');
                app.IMRTable.Data=tdata;
                %% State-space Table
                tdata3=table(MDResults.StatePF.StateString.', MDResults.StatePF.Val.');
                app.SSTable.Data=tdata3;
                app.SSTable.RowName=(1:length(app.GsysSs.A)).';
            end
        end

        % Cell selection callback: IMRTable
        function IMRTableCellSelection(app, event)
            indices = event.Indices;
            RowSel=indices(1,1);
            app.TuneButton.Text = ['Tune ',app.AppLegend{RowSel}];
            app.AppSelect = str2num(erase(app.AppLegend{RowSel},'A'));
        end

        % Callback function: not associated with a component
        function TuneButtonPushed(app, event)
            if app.AppSelect==0
                app.Label_2.Text='Select an apparatus in the table!';
            elseif app.ModeSelect==0
                app.Label_2.Text='Select a mode first!';
            else
                app.Label_2.Text='analysing...';
                %MDResults=SimplusGT.Modal.AppModalAnalysis(app.ModeSelect,app.AppSelect,1);
                MdLayer3=SimplusGT.Modal.AppModalLayer3(app.AppSelect,app.MdDataSave);
                for i=1:length(MdLayer3.Result)
                    ParaName(i)=MdLayer3.Result(i).ParaName;
                    DLambdaPu(i)=MdLayer3.Result(i).DLambdaRho_pu_Hz;
                    if(real(DLambdaPu(i))>1e-3)
                        Guidance{i}='decrease';
                    elseif(real(DLambdaPu(i))<-1e-3)
                        Guidance{i}='increase';
                    else
                        Guidance{i}='';
                    end
                end
                tData2=table(ParaName.', DLambdaPu.', Guidance.');
                app.TuningTable.Data=tData2;
                app.Label_2.Text='finished...';
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.GsysSs = evalin('base', 'GsysSs');
            app.Fbase = evalin('base', 'Fbase');
            app.ModeHz;
            app.ModeSelect=0;
            app.AppLegend;
            app.AppSelect;
            startupFcn(app);
        end

        % Value changed function: ModeIndexText
        function ModeIndexTextValueChanged(app, event)
            ID = app.ModeIndexText.Value;
            if ID>length(app.GsysSs.A)
                app.Label.Text = 'Invalid input!';
            else
                mx = real(app.ModeHz(:));
                my = imag(app.ModeHz(:));
                app.ModeHzLabel.Text= [num2str(mx(ID)),' + j',num2str(my(ID)),' Hz'];
                app.ModeSelect=ID;
                app.Label.Text = '';
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {785, 785, 785};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {785, 785};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {420, '1x', 397};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'lines');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModePlot = uiaxes(app.LeftPanel);
            title(app.ModePlot, 'System Modes (eigenvalue)')
            xlabel(app.ModePlot, 'real (Hz)')
            ylabel(app.ModePlot, 'imag (Hz)')
            zlabel(app.ModePlot, 'Z')
            app.ModePlot.XLim = [-80 20];
            app.ModePlot.YLim = [-150 150];
            app.ModePlot.ButtonDownFcn = createCallbackFcn(app, @ModePlotButtonDown, true);
            colormap(app.ModePlot, 'lines')
            app.SelectedModeLabel = uilabel(app.LeftPanel);
            app.SelectedModeLabel.Text = 'Selected Mode:';
            app.ModeIndexLabel_2 = uilabel(app.LeftPanel);
            app.ModeIndexLabel_2.Text = 'Mode Index';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.ModeHzLabel = uilabel(app.LeftPanel);
            app.ModeHzLabel.Text = '0+j0 Hz';
            app.Step1selecttheinterestedmodeontheeigenvalueplotLabel = uilabel(app.LeftPanel);
            app.Step1selecttheinterestedmodeontheeigenvalueplotLabel.Text = 'Step 1: select a mode on the eigenvalue plot, or enther a mode index';
            app.Step2clickRunbuttonLabel = uilabel(app.LeftPanel);
            app.Step2clickRunbuttonLabel.Text = 'Step 2: click ''Run'' button';
            app.Step3selectanapparatusinthetableandclickTuningbuttonLabel = uilabel(app.LeftPanel);
            app.Step3selectanapparatusinthetableandclickTuningbuttonLabel.Text = 'Step 3: select an apparatus in the table and click ''Tuning'' button';
            app.RefreshButton = uibutton(app.LeftPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ModeIndexText = uieditfield(app.LeftPanel, 'numeric');
            app.ModeIndexText.Limits = [1 Inf];
            app.ModeIndexText.ValueDisplayFormat = '%.0f';
            app.ModeIndexText.ValueChangedFcn = createCallbackFcn(app, @ModeIndexTextValueChanged, true);
            app.ModeIndexText.Value = 1;
            app.Step4refreshtheAPPifthesystemischangedLabel = uilabel(app.LeftPanel);
            app.Step4refreshtheAPPifthesystemischangedLabel.Text = 'Step 4: refresh the APP if the system is changed';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.BarChart2 = uiaxes(app.GridLayout3);
            title(app.BarChart2, 'Damping Sensitivity')
            app.BarChart2.YLim = [-1 1];
            colormap(app.BarChart2, 'lines')
            app.BarChart1 = uiaxes(app.GridLayout3);
            title(app.BarChart1, 'Frequency Sensitivity')
            app.BarChart1.YLim = [-1 1];
            colormap(app.BarChart1, 'lines')
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.IMRTable = uitable(app.GridLayout7);
            app.IMRTable.ColumnName = {'Apparatus'; 'Type'; 'IMR'; 'Strength'};
            app.IMRTable.RowName = {};
            app.IMRTable.CellSelectionCallback = createCallbackFcn(app, @IMRTableCellSelection, true);
            app.SmallsignalSystemStrengthLabel_2 = uilabel(app.GridLayout7);
            app.SmallsignalSystemStrengthLabel_2.Text = 'Small-signal System Strength';
            app.GridLayout8 = uigridlayout(app.GridLayout2);
            app.TuningTable = uitable(app.GridLayout8);
            app.TuningTable.ColumnName = {'Parameter'; 'Influence on Mode (Hz)'; 'Guidance'};
            app.TuningTable.RowName = {};
            app.GridLayout10 = uigridlayout(app.GridLayout8);
            app.TuneButton = uibutton(app.GridLayout10, 'push');
            app.TuneButton.Text = 'Tune';
            app.ParameterSensitivityLabel = uilabel(app.GridLayout10);
            app.ParameterSensitivityLabel.Text = 'Parameter Sensitivity';
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.ApparatusParticipationFactorLabel = uilabel(app.GridLayout9);
            app.ApparatusParticipationFactorLabel.Text = 'Apparatus Participation Factor';
            app.GridLayout12 = uigridlayout(app.GridLayout9);
            app.PieChart = uiaxes(app.GridLayout12);
            app.PieChart.XTick = [0 0.2 0.4 0.6 0.8 1];
            colormap(app.PieChart, 'lines')
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout11 = uigridlayout(app.RightPanel);
            app.SSTable = uitable(app.GridLayout11);
            app.SSTable.ColumnName = {'states'; 'PF'};
            app.SSTable.RowName = {};
            app.StateSpaceParticipationFactorLabel = uilabel(app.GridLayout11);
            app.StateSpaceParticipationFactorLabel.Text = 'State-Space Participation Factor';
            app.TheGreyboxApporachAnalysisLabel = uilabel(app.UIFigure);
            app.TheGreyboxApporachAnalysisLabel.Text = 'The Greybox Apporach Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModalAnalysisAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end