classdef ModalAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TextArea_2                matlab.ui.control.TextArea
        FrequencyLabel            matlab.ui.control.Label
        DampingLabel              matlab.ui.control.Label
        Layer2Label               matlab.ui.control.Label
        Layer1Label               matlab.ui.control.Label
        Step3entertheapparatusnumberforlayer3Label  matlab.ui.control.Label
        Step2clickRunbuttonLabel  matlab.ui.control.Label
        Step1selecttheinterestedmodeontheeigenvalueplotLabel  matlab.ui.control.Label
        IMRTable                  matlab.ui.control.Table
        TheGreyboxApporachbyYueZhuLabel  matlab.ui.control.Label
        TextArea                  matlab.ui.control.TextArea
        ModeIndexLabel            matlab.ui.control.Label
        ModeHzLabel               matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        RunButton                 matlab.ui.control.Button
        ModeIndexLabel_2          matlab.ui.control.Label
        SelectedModeLabel         matlab.ui.control.Label
        BarChart2                 matlab.ui.control.UIAxes
        BarChart1                 matlab.ui.control.UIAxes
        PieChart                  matlab.ui.control.UIAxes
        ModePlot                  matlab.ui.control.UIAxes
    end

    properties (Access = public)
        GminSS = evalin('base', 'GminSS');
        Fbase = evalin('base', 'Fbase');
        ModeHz;
        ModeSelect=0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %pole_sys = evalin('base', 'pole_sys'); %get data from workspace
            %% draw pole map.
            [~,D] = eig(app.GminSS.A);
            app.ModeHz=diag(D)/2/pi;
            f=app.ModePlot;
            lx=length(app.ModeHz);
            gx=cell(lx,1);
            mx = real(app.ModeHz);
            my = imag(app.ModeHz);
            for i=1:length(app.ModeHz)
                if abs(my(i))>10*abs(mx(i)) && abs(abs(my(i))-app.Fbase)>0.3 % 10% damping
                    gx{i}='poorly tuned';
                else 
                    gx{i}='normal';
                end
            end
            gscatter(f,mx,my,gx,'br','xo',9);
            f.XLim=[-20,2];
            f.YLim=[-100,100];
            f.Title.String='System Modes (eigenvalue)';
            f.XLabel.String = 'Real (Hz)';
            f.XLabel.String = 'Imag (Hz)';

            %% Charts
            PieData=[1,1,1,1,1,1]/2;
            AppLegend=[{'A1'},{'A2'},{'A3'},{'A4'},{'A5'}, {'A6'}];
            AppType=[{'GFL'},{'GFL'},{'GFL'},{'GFL'},{'GFL'},{'GFL'}];
            SimplusGT.Modal.Layer1PieChart(app.PieChart, PieData, AppLegend);
            SimplusGT.Modal.Layer2BarChart(app.BarChart1, PieData, AppLegend);
            SimplusGT.Modal.Layer2BarChart(app.BarChart2, PieData*(-1), AppLegend);
            %app.IMRTable.RowName=PieLegend;
            tdata=table(AppLegend',AppType',PieData',PieData');
            app.IMRTable.Data=tdata;
%            app.IMRTable.Data=[PieData*2;PieData;PieData]';
            %legend(app.PieChart, PieLegend, 'Location','eastoutside', 'NumColumns',3);
            %app.PieChart.Colormap = 
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            ax = app.UIFigure.CurrentAxes; % Gets the data from clicked axes
            axTitle=ax.Title.String;
            if(contains(axTitle,'eigenvalue')) %click on the eigenvalue plot
                pt = ax.CurrentPoint(1,1:2);
                mx = real(app.ModeHz(:));
                my = imag(app.ModeHz(:));
                coordinates = [mx(:),my(:)];     % matrix of your input coordinates
                dist_m = pdist2(pt,coordinates);
                if min(dist_m)<=2
                    [~, ID] = min(dist_m);            % index of minimum distance to points
                    %coordinateSelected = coordinates(minIdx,:); %the selected coordinate
                    %X_Start = Start_Points(1,1);
                    %Y_Start = Start_Points(1,2);
                    app.ModeIndexLabel.Text=num2str(ID);
                    app.ModeHzLabel.Text= [num2str(mx(ID)),' + j',num2str(my(ID)),' Hz'];
                    app.ModeSelect=ID;
                end
            end
        end

        % Button down function: ModePlot
        function ModePlotButtonDown(app, event)
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if app.ModeSelect==0
                app.Label.Text = 'Select a mode first!';
            else
                app.Label.Text = 'Calculating...';
                MDResults=SimplusGT.Modal.AppModalAnalysis(app.ModeSelect); % get modal results.
                AppratusNum = length(MDResults.Layer1.result);
                %close(2011) %close the original figure (only show it in the app)
                % arrange the plotting data
                for i=1:AppratusNum
                    PieData(i)=MDResults.Layer1.result(i).Abs_Max;
                    AppLegend(i)=MDResults.Layer1.result(i).Apparatus;
                    BarRealPu(i)=MDResults.Layer2.result(i).DeltaLambdaRealpu;
                    BarImagPu(i)=MDResults.Layer2.result(i).DeltaLambdaImagpu;
                    BarReal(i)=MDResults.Layer2.result(i).DeltaLambdaReal;
                    if BarReal(i)>0 && MDResults.IMR.IMRVal(i)<1
                        AppRole{i}='Weak: scale up';
                    elseif BarReal(i)<0 && MDResults.IMR.IMRVal(i)<1
                        AppRole{i}='Weak:scale down';
                    else
                        AppRole{i}='Strong';
                    end

                    type = MDResults.IMR.Type(i);
                    if type<10 || (type>=30 && type<40)
                        AppType{i}='SG';
                    elseif type>=10 && type<20
                        AppType{i}='GFL';
                    elseif type>=20 && type<30
                        AppType{i}='GFM';
                    else
                        AppType{i}='Unknown';
                    end
                    
                end

                %% Layer-1,2, charts
                SimplusGT.Modal.Layer1PieChart(app.PieChart, PieData, AppLegend);
                SimplusGT.Modal.Layer2BarChart(app.BarChart1, BarRealPu, AppLegend);
                SimplusGT.Modal.Layer2BarChart(app.BarChart2, BarImagPu, AppLegend);
                app.Label.Text = 'Finished!';
                %% IMR Table
                %app.IMRTable.RowName=AppLegend;
                %TableData = [MDResults.IMR.Type; BarReal; MDResults.IMR.IMRVal;]';
                tdata=table(AppLegend',AppType',MDResults.IMR.IMRVal',AppRole');
                app.IMRTable.Data=tdata;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'lines');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.ModePlot = uiaxes(app.UIFigure);
            title(app.ModePlot, 'System Modes (eigenvalue)')
            xlabel(app.ModePlot, 'real (Hz)')
            ylabel(app.ModePlot, 'imag (Hz)')
            zlabel(app.ModePlot, 'Z')
            app.ModePlot.XLim = [-80 20];
            app.ModePlot.YLim = [-150 150];
            app.ModePlot.ButtonDownFcn = createCallbackFcn(app, @ModePlotButtonDown, true);
            colormap(app.ModePlot, 'lines')
            app.PieChart = uiaxes(app.UIFigure);
            app.PieChart.XTick = [0 0.2 0.4 0.6 0.8 1];
            colormap(app.PieChart, 'lines')
            app.BarChart1 = uiaxes(app.UIFigure);
            app.BarChart1.YLim = [-1 1];
            colormap(app.BarChart1, 'lines')
            app.BarChart2 = uiaxes(app.UIFigure);
            app.BarChart2.YLim = [-1 1];
            colormap(app.BarChart2, 'lines')
            app.SelectedModeLabel = uilabel(app.UIFigure);
            app.SelectedModeLabel.Text = 'Selected Mode:';
            app.ModeIndexLabel_2 = uilabel(app.UIFigure);
            app.ModeIndexLabel_2.Text = 'Mode Index';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ModeHzLabel = uilabel(app.UIFigure);
            app.ModeHzLabel.Text = '0+j0 Hz';
            app.ModeIndexLabel = uilabel(app.UIFigure);
            app.ModeIndexLabel.Text = '0';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'This layer shows the variation of the mode in both real and imaginary part when increasing the impedance of the apparatus (equivilently as scaling down). '; 'General principle: if a positive bar is found in damping plot,  it implies that scaling up the corresponding apparatus connected at the node tends to stabilize the system , and vice versa. '; 'All values are normalised to 1.'};
            app.TheGreyboxApporachbyYueZhuLabel = uilabel(app.UIFigure);
            app.TheGreyboxApporachbyYueZhuLabel.Text = 'The Greybox Apporach (by Yue Zhu)';
            app.IMRTable = uitable(app.UIFigure);
            app.IMRTable.ColumnName = {'Apparatus'; 'Type'; 'IMR'; 'Strength Analysis'};
            app.IMRTable.RowName = {};
            app.Step1selecttheinterestedmodeontheeigenvalueplotLabel = uilabel(app.UIFigure);
            app.Step1selecttheinterestedmodeontheeigenvalueplotLabel.Text = 'Step 1: select the interested mode on the eigenvalue plot';
            app.Step2clickRunbuttonLabel = uilabel(app.UIFigure);
            app.Step2clickRunbuttonLabel.Text = 'Step 2: click ''Run'' button';
            app.Step3entertheapparatusnumberforlayer3Label = uilabel(app.UIFigure);
            app.Step3entertheapparatusnumberforlayer3Label.Text = '* Step 3: enter the apparatus number for layer 3';
            app.Layer1Label = uilabel(app.UIFigure);
            app.Layer1Label.Text = 'Layer 1';
            app.Layer2Label = uilabel(app.UIFigure);
            app.Layer2Label.Text = 'Layer 2';
            app.DampingLabel = uilabel(app.UIFigure);
            app.DampingLabel.Text = 'Damping';
            app.FrequencyLabel = uilabel(app.UIFigure);
            app.FrequencyLabel.Text = 'Frequency';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'This layer helps you quickly determine the potential participants of the selected mode'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModalAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end